classdef app1_funzionante_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelezionacartellaButton         matlab.ui.control.Button
        AnalisiimmaginiPETPanel         matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        VisualizzazioneTab              matlab.ui.container.Tab
        SelezionaimmagineDropDownLabel  matlab.ui.control.Label
        SelezionaimmagineDropDown       matlab.ui.control.DropDown
        MostraimmagineButton            matlab.ui.control.Button
        ModellierisultatiTab            matlab.ui.container.Tab
        MostrarisultatiButton           matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        AvanzateButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Y % target vector of omo/eterogeneous lesions
        stacknii % struct of all read nifti
        files % vector of images name found
        features % struct of all features calculated from get_features
    end
    
    methods (Access = public)
        
        function features = get_features(~, stacknii) %(app, ...) app unused
            features = calc_features(stacknii);
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelezionacartellaButton
        function SelezionacartellaButtonPushed(app, event)
            path = uigetdir();
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on'; % otherwise the window goes on background
            files_o = dir(fullfile([path '\homogeneous\*.nii']));
            files_e = dir(fullfile([path '\heterogeneous\*.nii']));
            files_all = [files_o; files_e];
            % Create target vector
            n_o = size(files_o, 1); % Number of homogeneous tumor patients (obs)
            n_e = size(files_e, 1); % Number of heterogeneous tumor patients (obs)
            isHeterogeneous = [zeros(n_o, 1); ones(n_e, 1)];
            
            for n = 1:size(files_all, 1)
                pathtemp = [files_all(n).folder '\' files_all(n).name];
                nii = load_nii(pathtemp);
                
                stacknifti(n, :) = nii;
                %stackimg(n, :, :, :) = double(nii.img);
            end
            
            % Set global app values
            app.Y = isHeterogeneous;
            app.stacknii = stacknifti;
            
            % Populate dropDown with images names
            ListOfFiles = {files_all(:).name};
            set(app.SelezionaimmagineDropDown, 'Items', ListOfFiles)
            set(app.SelezionaimmagineDropDown, 'Value', ListOfFiles(1))
            set(app.SelezionaimmagineDropDown, 'ItemsData', stacknifti)

        end

        % Button pushed function: MostraimmagineButton
        function showImg2(app, event)
            nii = app.SelezionaimmagineDropDown.Value;

            % "Fix" the visualization based on the output/color scale
            minimum = min(min(min(nii.img)));% disp(num2str(minimum));
            maximum = max(max(max(nii.img)));% disp(num2str(maximum));
            % Normalize the image between 0 and 1
            img = nii.img - minimum;
            img = img / maximum;
            img = img * 1;
           
            % Visualize image
            figure();
            montage(img);
            colormap(jet)
            caxis('auto');
            colorbar
            set(gcf, 'Name', ['18F-FDG PET-CT ' nii.fileprefix]);
            %app.SelezionaimmagineDropDown.ItemsData(i).fileprefix
        end

        % Value changed function: MostrarisultatiButton
        function showResult(app, event)
            value = app.MostrarisultatiButton.Value;
            app.UITable.Visible = true;
            %% Create struct of features
            app.features = app.get_features(app.stacknii);
            %%% TODO %%%
            %[app.lassofit, app.enfit, app.svmfit] = app.train_classifiers(app.Y, app.features);
            % Test: OK !
            app.UITable.Data = {'test';'foo'; app.features(1).mtv};
        end

        % Button pushed function: AvanzateButton
        function AvanzateButtonPushed(app, event)
            app.TabGroup2.Visible = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelezionacartellaButton = uibutton(app.UIFigure, 'push');
            app.SelezionacartellaButton.ButtonPushedFcn = createCallbackFcn(app, @SelezionacartellaButtonPushed, true);
            app.SelezionacartellaButton.Tooltip = {'Folder of homog. and heterog. lesions'};
            app.SelezionacartellaButton.Text = 'Seleziona cartella';
            app.AnalisiimmaginiPETPanel = uipanel(app.UIFigure);
            app.AnalisiimmaginiPETPanel.Title = 'Analisi immagini PET';
            app.TabGroup = uitabgroup(app.AnalisiimmaginiPETPanel);
            app.VisualizzazioneTab = uitab(app.TabGroup);
            app.VisualizzazioneTab.Title = 'Visualizzazione';
            app.SelezionaimmagineDropDownLabel = uilabel(app.VisualizzazioneTab);
            app.SelezionaimmagineDropDownLabel.Text = 'Seleziona immagine';
            app.SelezionaimmagineDropDown = uidropdown(app.VisualizzazioneTab);
            app.SelezionaimmagineDropDown.Items = {'None'};
            app.SelezionaimmagineDropDown.Value = 'None';
            app.MostraimmagineButton = uibutton(app.VisualizzazioneTab, 'push');
            app.MostraimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @showImg2, true);
            app.MostraimmagineButton.Text = 'Mostra immagine';
            app.ModellierisultatiTab = uitab(app.TabGroup);
            app.ModellierisultatiTab.Title = 'Modelli e risultati';
            app.MostrarisultatiButton = uibutton(app.ModellierisultatiTab, 'state');
            app.MostrarisultatiButton.ValueChangedFcn = createCallbackFcn(app, @showResult, true);
            app.MostrarisultatiButton.Text = 'Mostra risultati';
            app.UITable = uitable(app.ModellierisultatiTab);
            app.UITable.ColumnName = {'Performance'};
            app.UITable.RowName = {'Acc.'; 'Sens.'; 'Spec.'};
            app.UITable.ColumnEditable = false;
            app.UITable.Visible = 'off';
            app.AvanzateButton = uibutton(app.ModellierisultatiTab, 'push');
            app.AvanzateButton.ButtonPushedFcn = createCallbackFcn(app, @AvanzateButtonPushed, true);
            app.AvanzateButton.Text = 'Avanzate...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1_funzionante_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end